classdef WidefieldAquisitionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button                         matlab.ui.control.Button
        CMOSpulseButton                matlab.ui.control.Button
        RECORDButton                   matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        EditRoutineButton              matlab.ui.control.Button
        RecordingRoutineDropDown       matlab.ui.control.DropDown
        RecordingRoutineDropDownLabel  matlab.ui.control.Label
        ExperimentEditField            matlab.ui.control.EditField
        ExperimentEditFieldLabel       matlab.ui.control.Label
        SaveDirectoryEditField         matlab.ui.control.EditField
        SaveDirectoryEditFieldLabel    matlab.ui.control.Label
        ExperimenterEditField          matlab.ui.control.EditField
        ExperimenterEditFieldLabel     matlab.ui.control.Label
        MouseEditField                 matlab.ui.control.EditField
        MouseEditFieldLabel            matlab.ui.control.Label
        WidefieldImagingDataAquisitionLabel  matlab.ui.control.Label
    end


    properties (Access = public) %Global variables (Access = private; are the local variables to this app window)
        rootdir %The root directory of the GUI, flexibly identified for locating all files and setting path
        cur_routine_vars = [];
        cur_routine_vals = [];
        behav_cam_vars = [];
        behav_cam_vals = [];
        nidq_cmos; % Property to hold the nidaq object
    end

    methods (Access = private)
        %% Warning Alert Functions
        %Missing variable

        %%CHANGE TO REMOVE THE FOR LOOP
        function MissingVariable_catch(app,missing_var)
            for i = 1:numel(missing_var)
                if ~exist(missing_var{i})
                    uialert(app.UIFigure,sprintf('%s Variable has not been defined yet',missing_var{i}),'Undefined Variable')
                end
            end
        end


        %%Excessive Frame Rate
        function fps_warning(app)
            uialert(app.UIFigure,sprintf(['Frame Rates Above 60fps May',...
                'Results in Synching Issues and Dropped Frames...',...
                '\n\nConfirm All Cameras Are Capable']),'Excessive FPS')
        end
        %General Behavioral Camera Error
        function behav_cam_err(app)
            uialert(app.UIFigure,sprintf(['Behavioral Camera Aquisition Error.',...
                'Check Camera(s) Plugged in and Recognized by Computer']),'Behavior Camera(s) Failure')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Add all the GUI subfolders to your path
            app.rootdir = fileparts(which('WidefieldAquisitionApp.mlapp'));
            if isempty(app.rootdir); error("Failed to find WidefieldAquisitionApp.mlapp in matlab path."); end
            addpath(genpath(app.rootdir));

            % Initialize nidaq object for CMOS pulse generation
            app.nidq_cmos = daq("ni");
            app.nidq_cmos.Rate = 3*1e5; % 300KHz
            ch_cmos = addoutput(app.nidq_cmos, "Dev1", 'ctr0', "PulseGeneration"); % PFI12 on BNC-2110
            ch_cmos.Frequency = 40;
            ch_cmos.DutyCycle = 0.7;

            %Default savedir
            app.SaveDirectoryEditField.Value = [app.rootdir filesep 'scratch'];

            %Load the available routines
            routines = getRoutines([app.rootdir filesep 'Routine_Configuration']);
            app.RecordingRoutineDropDown.Items = routines;
            app.RecordingRoutineDropDown.Value = 'choose_routine';

            %Load the default behavioral camera values
            [app.behav_cam_vars,app.behav_cam_vals] = BehavioralCameraConfiguration();

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %User selects save directory
            app.SaveDirectoryEditField.Value = uigetdir('C:\');
        end

        % Button pushed function: EditRoutineButton
        function EditRoutineButtonPushed(app, event)
            %Catch to make sure it's not on the default place holder routine
            if strcmp(app.RecordingRoutineDropDown.Value,'choose_routine')
                uialert(app.UIFigure,'You First Must Choose a Routine','Missing Routine')
                return
            end

            temp_routine = DynamicDialog(app.cur_routine_vars,app.cur_routine_vals);
            if ~isempty(temp_routine) %repopulate the routine options.
                app.cur_routine_vals = temp_routine;
            end
            %Update frame rate
            app.cur_routine_vals.framerate =1000/app.cur_routine_vals.exposure_duration;

            %Update the mutable fields in the GUI
            app.ExperimentEditField.Value = app.cur_routine_vals.experiment_type;
            app.ExperimenterEditField.Value = app.cur_routine_vals.experimenter;
            app.MouseEditField.Value = app.cur_routine_vals.mouse;
            app.behav_cam_vals.duration_in_sec = app.cur_routine_vals.recording_duration;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete app
            delete(app)
        end

        % Value changed function: RecordingRoutineDropDown
        function RecordingRoutineDropDownValueChanged(app, event)
            %Load data from the selected routine
            [app.cur_routine_vars, app.cur_routine_vals] = feval(app.RecordingRoutineDropDown.Value,...
                app.MouseEditField.Value,...
                app.ExperimenterEditField.Value,...
                app.ExperimentEditField.Value);
            app.behav_cam_vals.opts.duration_in_sec = app.cur_routine_vals.recording_duration; %Make behavioral camera recording equal duration
        end

        % Value changed function: MouseEditField
        function MouseEditFieldValueChanged(app, event)
            if ~isempty(app.cur_routine_vals) %if you have already populated the routine options, then change these values. Otherwise skip
                app.cur_routine_vals.mouse = app.MouseEditField.Value;
            end
        end

        % Value changed function: ExperimenterEditField
        function ExperimenterEditFieldValueChanged(app, event)
            if ~isempty(app.cur_routine_vals) %if you have already populated the routine options, then change these values. Otherwise skip
                app.cur_routine_vals.experimenter = app.ExperimenterEditField.Value;
            end
        end

        % Value changed function: ExperimentEditField
        function ExperimentEditFieldValueChanged(app, event)
            if ~isempty(app.cur_routine_vals) %if you have already populated the routine options, then change these values. Otherwise skip
                app.cur_routine_vals.ExperimentType = app.ExperimentEditField.Value;
            end

        end

        % Callback function
        function TestCamerasButtonPushed(app, event)
            if app.ofCamsEditField.Value>0
                try
                    TestBehavioralCam(app);
                catch
                    behav_cam_err(app);
                end
            else
                uialert(app.UIFigure,'No cameras selected','zero cameras')
            end
        end

        % Button pushed function: RECORDButton
        function RECORDButtonPushed(app, event)
            if isempty(app.cur_routine_vals)
                uialert(app.UIFigure,'Select routine before recording','Missing Routine');
                return
            end

            %Run and aquire
            app.RECORDButton.BackgroundColor = [0.35 0 0]; %Dark Red to indicate recording

            try
                feval(app.cur_routine_vals.routine_name,app);
                try
                    if ~isempty(app.nidq_cmos) % Check if the session exists
                        stop(app.nidq_cmos);   % Stop the session if it exists
                    end
                catch ME
                    warning('Error stopping or releasing nidq_cmos: %s', ME.message);
                end

            catch
                warning('ran into error')
                app.RECORDButton.BackgroundColor = [1 0 0]; %Reset red if issue   %%%NEED TO INCLUDE ERR MESSAGE HERE
            end

            %Status Indicator
            app.RECORDButton.BackgroundColor = [1 0 0]; %Reset Red

        end

        % Callback function
        function ConfigureBehavCamButtonPushed(app, event)
            %Load and Edit Camera Configuration Buttons
            temp_behavioral_cam = DynamicDialog(app.behav_cam_vars,app.behav_cam_vals);
            if ~isempty(temp_behavioral_cam) %repopulate the routine options.
                app.behav_cam_vals = temp_behavioral_cam;
            end

            %Update the mutable fields in the GUI
            app.ofCamsEditField.Value = app.behav_cam_vals.num_cam;

        end

        % Callback function
        function ofCamsEditFieldValueChanged(app, event)
            app.behav_cam_vals.num_cam = app.ofCamsEditField.Value;
        end

        % Button pushed function: CMOSpulseButton
        function CMOSpulseOn(app, event)
            persistent isOn;
            if isempty(isOn)
                isOn = false;
            end

            if ~isOn
                % Start CMOS pulse and store nidaq object in app property
                start(app.nidq_cmos, "Continuous");

                % Update button appearance to 'Off'
                app.CMOSpulseButton.Text = 'CMOS pulse Off';
                app.CMOSpulseButton.FontColor = [0 0 0.5];  % Navy
                isOn = true;
            else
                % Stop CMOS pulse using the app property
                stop(app.nidq_cmos);

                % Update button appearance to 'On'
                app.CMOSpulseButton.Text = 'CMOS pulse On';
                app.CMOSpulseButton.FontColor = [0 0 1];  % Blue
                isOn = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WidefieldImagingDataAquisitionLabel = uilabel(app.UIFigure);
            app.WidefieldImagingDataAquisitionLabel.Text = 'Widefield Imaging Data Aquisition';
            app.MouseEditFieldLabel = uilabel(app.UIFigure);
            app.MouseEditFieldLabel.Text = 'Mouse';
            app.MouseEditField = uieditfield(app.UIFigure, 'text');
            app.MouseEditField.ValueChangedFcn = createCallbackFcn(app, @MouseEditFieldValueChanged, true);
            app.MouseEditField.Value = '1';
            app.ExperimenterEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimenterEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimenterEditFieldValueChanged, true);
            app.ExperimenterEditField.Value = 'Junchol';
            app.SaveDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory';
            app.SaveDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDirectoryEditField.Value = 'C:\Users\buschmanlab\Documents\Widefield_Imaging_Control\scratch';
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentEditFieldValueChanged, true);
            app.ExperimentEditField.Value = 'restingstate';
            app.RecordingRoutineDropDownLabel = uilabel(app.UIFigure);
            app.RecordingRoutineDropDownLabel.Text = 'Recording Routine';
            app.RecordingRoutineDropDown = uidropdown(app.UIFigure);
            app.RecordingRoutineDropDown.Items = {'select_routine', 'Routine1', 'Routine2', 'Routine3'};
            app.RecordingRoutineDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingRoutineDropDownValueChanged, true);
            app.RecordingRoutineDropDown.Value = 'select_routine';
            app.EditRoutineButton = uibutton(app.UIFigure, 'push');
            app.EditRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @EditRoutineButtonPushed, true);
            app.EditRoutineButton.Text = 'Edit Routine';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.Text = 'RESET';
            app.RECORDButton = uibutton(app.UIFigure, 'push');
            app.RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @RECORDButtonPushed, true);
            app.RECORDButton.Text = 'RECORD';
            app.CMOSpulseButton = uibutton(app.UIFigure, 'push');
            app.CMOSpulseButton.ButtonPushedFcn = createCallbackFcn(app, @CMOSpulseOn, true);
            app.CMOSpulseButton.Text = 'CMOS pulse On';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WidefieldAquisitionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end