classdef WidefieldAquisitionApp_draft0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WidefieldImagingDataAquisitionLabel  matlab.ui.control.Label
        MouseEditFieldLabel            matlab.ui.control.Label
        MouseEditField                 matlab.ui.control.EditField
        ExperimenterEditFieldLabel     matlab.ui.control.Label
        ExperimenterEditField          matlab.ui.control.EditField
        SaveDirectoryEditFieldLabel    matlab.ui.control.Label
        SaveDirectoryEditField         matlab.ui.control.EditField
        Button                         matlab.ui.control.Button
        EditRoutineButton              matlab.ui.control.Button
        ExperimentEditFieldLabel       matlab.ui.control.Label
        ExperimentEditField            matlab.ui.control.EditField
        RecordingRoutineDropDownLabel  matlab.ui.control.Label
        RecordingRoutineDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private) %These are the local variables to this frame of the app (Access = public; are the Global variables) 
        RoutineDialog % Description
        cur_routine_options %The Routine Variables that will be used
    end
   
    
    methods (Access = public)
        
        function update_cur_routine(app,cur_routine_options)
            %% ADD OUTPUT FOR UPDATING THE ROUTINE
            
        end
    end
    
    methods (Access = private)
        
        function MissingVariable_catch(app,missing_var)
            if ~exist(missing_var)
                uialert(app.UIFigure,sprintf('%s Variable has not been defined yet',missing_var),'Undefined Variable')            
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %Add all the GUI subfolders to your path
           rootdir = fileparts(which('WidefieldAquisitionApp.mlapp'));
           if isempty(rootdir); error("Failed to find WidefieldAquisitionApp.mlapp in matlab path."); end           
           addpath(genpath(rootdir));
           
           %Default savedir
           app.SaveDirectoryEditField.Value = [rootdir filesep 'saved_data' filesep];
           
           %Load the available routines
           routines = getRoutines([rootdir filesep 'Routines']);                     
           app.RecordingRoutineDropDown.Items = routines; 
           app.RecordingRoutineDropDown.Value = 'choose_routine';
           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %User selects save directory              
            app.SaveDirectoryEditField.Value = uigetdir('C:\');
        end

        % Button pushed function: EditRoutineButton
        function EditRoutineButtonPushed(app, event)
            %Catch to make sure it's not on the default place holder routine
            if strcmp(app.RecordingRoutineDropDown.Value,'choose_routine')
                uialert(app.UIFigure,'You First Must Choose a Routine','Missing Routine')        
                return
            end
%             app.EditRoutineButton.Enable = 'off';
            resp = DynamicDialog(;
            
            %Call the Edit Routine Dialog Box
%             app.RoutineDialog = Edit_Routine_Dialog(app, app.cur_routine_options);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           % Delete app and dialogs
           delete(app.DialogApp)
           delete(app)                
        end

        % Value changed function: RecordingRoutineDropDown
        function RecordingRoutineDropDownValueChanged(app, event)

            
            %Load data from the selected routine
            app.cur_routine_options = feval(app.RecordingRoutineDropDown.Value,...
                app.SaveDirectoryEditField.Value,...
                app.MouseEditField.Value,...
                app.ExperimenterEditField.Value,...
                app.ExperimentEditField.Value);             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WidefieldImagingDataAquisitionLabel = uilabel(app.UIFigure);
            app.WidefieldImagingDataAquisitionLabel.Text = 'Widefield Imaging Data Aquisition';
            app.MouseEditFieldLabel = uilabel(app.UIFigure);
            app.MouseEditFieldLabel.Text = 'Mouse';
            app.MouseEditField = uieditfield(app.UIFigure, 'text');
            app.MouseEditField.Value = '1';
            app.ExperimenterEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimenterEditField.Value = 'elmo';
            app.SaveDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory';
            app.SaveDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDirectoryEditField.Value = 'C:\Users\mouse1\Desktop';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditField.Value = 'restingstate';
            app.RecordingRoutineDropDownLabel = uilabel(app.UIFigure);
            app.RecordingRoutineDropDownLabel.Text = 'Recording Routine';
            app.RecordingRoutineDropDown = uidropdown(app.UIFigure);
            app.RecordingRoutineDropDown.Items = {'select_routine', 'Routine1', 'Routine2', 'Routine3'};
            app.RecordingRoutineDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingRoutineDropDownValueChanged, true);
            app.RecordingRoutineDropDown.Value = 'select_routine';
            app.EditRoutineButton = uibutton(app.UIFigure, 'push');
            app.EditRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @EditRoutineButtonPushed, true);
            app.EditRoutineButton.Text = 'Edit Routine';
        end
    end

    methods (Access = public)

        % Construct app
        function app = WidefieldAquisitionApp_draft0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end